classdef GUI_sensores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        SubidaAxes               matlab.ui.control.UIAxes
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        VideoButton              matlab.ui.control.Button
        InformeButton            matlab.ui.control.Button
        GrficasButton            matlab.ui.control.Button
        AvatarButton             matlab.ui.control.Button
        GridLayout5              matlab.ui.container.GridLayout
        MedicionButton           matlab.ui.control.Button
        SensoresLabel            matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        NombreLabel2             matlab.ui.control.Label
        FechaLabel2              matlab.ui.control.Label
        PesoLabel2               matlab.ui.control.Label
        EdadLabel2               matlab.ui.control.Label
        ApellidosLabel2          matlab.ui.control.Label
        FechaLabel               matlab.ui.control.Label
        PesoLabel                matlab.ui.control.Label
        EdadLabel                matlab.ui.control.Label
        ApellidosLabel           matlab.ui.control.Label
        NombreLabel              matlab.ui.control.Label
        CaptacindesensoresLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             global nombre apellidos edad peso;

            load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
              global nombre apellidos edad peso;
            GUI_informe;
            app.delete;
        end

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
             global nombre apellidos edad peso;
            GUI_graficas;
            app.delete;
        end

        % Callback function
        function CaderaButtonPushed(app, event)
%             RPY;
% 
%             plot(app.SubidaAxes);
           
        end

        % Callback function
        function RodillaButtonPushed(app, event)
%             RPY;
% 
%             plot(app.SubidaAxes,'r');
            
        end

        % Callback function
        function TobilloButtonPushed(app, event)
%             RPY;
% 
%             plot(app.SubidaAxes,'t');
%           
        end

        % Button pushed function: MedicionButton
        function MedicionButtonPushed(app, event)
             RPY;
            hold(app.SubidaAxes,"on");
            plot(app.SubidaAxes,rodilla,'r');
            plot(app.SubidaAxes,tobillo,'b');
            hold(app.SubidaAxes,"off");
            drawnow;
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            global nombre apellidos edad peso;
            Marcadores;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'SMA_1-PhotoRoom.png';
            app.CaptacindesensoresLabel = uilabel(app.GridLayout);
            app.CaptacindesensoresLabel.Text = 'Captación de sensores';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout4);
            app.NombreLabel.Text = 'Nombre:';
            app.ApellidosLabel = uilabel(app.GridLayout4);
            app.ApellidosLabel.Text = 'Apellidos:';
            app.EdadLabel = uilabel(app.GridLayout4);
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout4);
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout4);
            app.FechaLabel.Text = 'Fecha:';
            app.ApellidosLabel2 = uilabel(app.GridLayout4);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout4);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout4);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout4);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.NombreLabel2 = uilabel(app.GridLayout4);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.SensoresLabel = uilabel(app.GridLayout5);
            app.SensoresLabel.Text = 'Sensores:';
            app.MedicionButton = uibutton(app.GridLayout5, 'push');
            app.MedicionButton.ButtonPushedFcn = createCallbackFcn(app, @MedicionButtonPushed, true);
            app.MedicionButton.Text = 'Medicion';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout6, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout6, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Text = 'Gráficas';
            app.InformeButton = uibutton(app.GridLayout6, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Text = 'Informe';
            app.VideoButton = uibutton(app.GridLayout6, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SubidaAxes = uiaxes(app.GridLayout3);
            title(app.SubidaAxes, 'Subida')
            xlabel(app.SubidaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaAxes, 'Ángulo (º)')
            zlabel(app.SubidaAxes, 'Z')
            app.SubidaAxes.Tag = 'axesSubida';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_sensores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end